classdef teachGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        q7Slider            matlab.ui.control.Slider
        q7SliderLabel       matlab.ui.control.Label
        q6Slider            matlab.ui.control.Slider
        q6SliderLabel       matlab.ui.control.Label
        q5Slider            matlab.ui.control.Slider
        q5SliderLabel       matlab.ui.control.Label
        q4Slider            matlab.ui.control.Slider
        q4SliderLabel       matlab.ui.control.Label
        q3Slider            matlab.ui.control.Slider
        q3SliderLabel       matlab.ui.control.Label
        q2Slider            matlab.ui.control.Slider
        q2SliderLabel       matlab.ui.control.Label
        q1Slider            matlab.ui.control.Slider
        q1SliderLabel       matlab.ui.control.Label
        XSpinner            matlab.ui.control.Spinner
        XSpinnerLabel       matlab.ui.control.Label
        YSpinner            matlab.ui.control.Spinner
        YSpinnerLabel       matlab.ui.control.Label
        ZSpinner            matlab.ui.control.Spinner
        ZSpinnerLabel       matlab.ui.control.Label
        q1Value             matlab.ui.control.NumericEditField
        q2Value             matlab.ui.control.NumericEditField
        q3Value             matlab.ui.control.NumericEditField
        q4Value             matlab.ui.control.NumericEditField
        q6Value             matlab.ui.control.NumericEditField
        q5Value             matlab.ui.control.NumericEditField
        RobotDropDown       matlab.ui.control.DropDown
        RobotDropDownLabel  matlab.ui.control.Label
        plot                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here
        modelDenso % Where I will put the loaded puma
        modelLinearUR5 % Where I will put the loaded UR5
        qDenso = [0,0,0,0,0,0];
        qLinearUR5 = deg2rad([-0.01, 0,-90,-90,0,90,0]);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            % Initialize the UR5 robot
        
            % Code to run once GUI is loaded successfully
            ax = gca;
            axis([-1.5,1.5,-1.5,1.5,0.0,1.5]);
            hold on; 

            % Make, save and plot some robots            
            app.modelDenso = OurDensoVS068; 
            app.modelDenso.model.base = eye(4)*transl(-0.5,-0.9,0.25)*trotz(-pi/2);
            app.modelDenso.model.animate(app.qDenso);

            app.modelLinearUR5 = OurLinearUR5;
            app.modelLinearUR5.model.base = eye(4)*transl(-0.5,-0.9,0.25)*trotx(pi/2);
            app.modelLinearUR5.model.animate(app.qLinearUR5);

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
         
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueQ1 = app.q1Slider.Value;                                   % get slider value
                app.qDenso(1,1) = deg2rad(valueQ1);                               % update stored qDenso in rads
                app.modelDenso.model.animate(app.qDenso);                           % update model
                app.q1Value.Value = valueQ1;                                    % update textbox
                
                % get XYZ and update spinner values
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            
            else
                valueQ1 = app.q1Slider.Value;                                   % get slider value
                app.qLinearUR5(1,1) = valueQ1;                               % update stored qDenso in rads
                app.modelLinearUR5.model.animate(app.qLinearUR5);                           % update model
                app.q1Value.Value = valueQ1;                                    % update textbox
                
                % get XYZ and update spinner values
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            % valueQ1Slider = app.q1Slider.Value;
            % % Map the slider value to the joint value within the range [-0.8, -0.1]
            % minSlider = app.q1Slider.Limits(1);
            % maxSlider = app.q1Slider.Limits(2);
            % minJoint = -0.8;  % Minimum joint value
            % maxJoint = -0.1;  % Maximum joint value
            % app.q1Value = interp1([minSlider, maxSlider], [minJoint, maxJoint], valueQ1Slider);
            % 
            % % Update the q1 value in the UR5 model
            % app.qLinearUR5(1, 1) = app.q1Value;
            % app.modelLinearUR5.model.animate(app.qLinearUR5);
            % 
            % % Update the numeric edit field
            % app.q1Value.Value = rad2deg(app.q1Value);  % Display the value in degrees
            % 
            % % Get XYZ and update spinner values
            % XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
            % app.XSpinner.Value = XYZ(1, 1);
            % app.YSpinner.Value = XYZ(1, 2);
            % app.ZSpinner.Value = XYZ(1, 3);
            end
            
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueQ2 = app.q2Slider.Value;                                   % get slider value
                app.qDenso(1,2) = deg2rad(valueQ2);                               % update stored qDenso in rads
                app.modelDenso.model.animate(app.qDenso);                           % update model
                app.q2Value.Value = valueQ2;                                    % update textbox
                
                % get XYZ and update spinner values
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            else
                valueQ2 = app.q2Slider.Value;                                   % get slider value
                app.qLinearUR5(1,2) = deg2rad(valueQ2);                               % update stored qDenso in rads
                app.modelLinearUR5.model.animate(app.qLinearUR5);                           % update model
                app.q2Value.Value = valueQ2;                                    % update textbox
                
                % get XYZ and update spinner values
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            end
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueQ3 = app.q3Slider.Value;                                   % get slider value
                app.qDenso(1,3) = deg2rad(valueQ3);                               % update stored qDenso in rads
                app.modelDenso.model.animate(app.qDenso);                           % update model
                app.q3Value.Value = valueQ3;                                    % update textbox
                
                % get XYZ and update spinner values
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            else
                valueQ3 = app.q3Slider.Value;                                   % get slider value
                app.qLinearUR5(1,3) = deg2rad(valueQ3);                               % update stored qDenso in rads
                app.modelLinearUR5.model.animate(app.qLinearUR5);                           % update model
                app.q3Value.Value = valueQ3;                                    % update textbox
                
                % get XYZ and update spinner values
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            end
            
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"  
                valueQ4 = app.q4Slider.Value;                                   % get slider value
                app.qDenso(1,4) = deg2rad(valueQ4);                               % update stored qDenso in rads
                app.modelDenso.model.animate(app.qDenso);                           % update model
                app.q4Value.Value = valueQ4;                                    % update textbox
                
                % get XYZ and update spinner values
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            else
                valueQ4 = app.q4Slider.Value;                                   % get slider value
                app.qLinearUR5(1,4) = deg2rad(valueQ4);                               % update stored qDenso in rads
                app.modelLinearUR5.model.animate(app.qLinearUR5);                           % update model
                app.q4Value.Value = valueQ4;                                    % update textbox
                
                % get XYZ and update spinner values
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            end
            
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueQ5 = app.q5Slider.Value;                                   % get slider value
                app.qDenso(1,5) = deg2rad(valueQ5);                               % update stored qDenso in rads
                app.modelDenso.model.animate(app.qDenso);                           % update model
                app.q5Value.Value = valueQ5;                                    % update textbox
                
                % get XYZ and update spinner values
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
           else
                valueQ5 = app.q5Slider.Value;                                   % get slider value
                app.qLinearUR5(1,5) = deg2rad(valueQ5);                               % update stored qDenso in rads
                app.modelLinearUR5.model.animate(app.qLinearUR5);                           % update model
                app.q5Value.Value = valueQ5;                                    % update textbox
                
                % get XYZ and update spinner values
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
           end
        
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueQ6 = app.q6Slider.Value;                                   % get slider value
                app.qDenso(1,6) = deg2rad(valueQ6);                               % update stored qDenso in rads
                app.modelDenso.model.animate(app.qDenso);                           % update model
                app.q6Value.Value = valueQ6;                                    % update textbox
                
                % get XYZ and update spinner values
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            else
                valueQ6 = app.q6Slider.Value;                                   % get slider value
                app.qLinearUR5(1,6) = deg2rad(valueQ6);                               % update stored qDenso in rads
                app.modelLinearUR5.model.animate(app.qLinearUR5);                           % update model
                app.q6Value.Value = valueQ6;                                    % update textbox
                
                % get XYZ and update spinner values
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            end
            
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueX = app.XSpinner.Value;                                    % get spinner value
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));               % get old XYZ value from qDenso
                XYZ(1,1) = valueX;                                              % update X
                q1 = app.modelDenso.model.ikcon(transl(XYZ));                     % get new q
                app.modelDenso.model.animate(q1);                                 % update model
                
                % store new qDenso
                app.qDenso = q1;
                
                % update slider values in degrees
                app.q1Slider.Value = rad2deg(q1(1,1));
                app.q2Slider.Value = rad2deg(q1(1,2));
                app.q3Slider.Value = rad2deg(q1(1,3));
                app.q4Slider.Value = rad2deg(q1(1,4));
                app.q5Slider.Value = rad2deg(q1(1,5));
                app.q6Slider.Value = rad2deg(q1(1,6));
                
                % update textbox values in degrees
                app.q1Value.Value = rad2deg(q1(1,1));
                app.q2Value.Value = rad2deg(q1(1,2));
                app.q3Value.Value = rad2deg(q1(1,3));
                app.q4Value.Value = rad2deg(q1(1,4));
                app.q5Value.Value = rad2deg(q1(1,5));
                app.q6Value.Value = rad2deg(q1(1,6));
            else
                valueX = app.XSpinner.Value;                                    % get spinner value
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));               % get old XYZ value from qDenso
                XYZ(1,1) = valueX;                                              % update X
                q1 = app.modelLinearUR5.model.ikcon(transl(XYZ));                     % get new q
                app.modelLinearUR5.model.animate(q1);                                 % update model
                
                % store new qDenso
                app.qLinearUR5 = q1;
                
                % update slider values in degrees
                app.q1Slider.Value = rad2deg(q1(1,1));
                app.q2Slider.Value = rad2deg(q1(1,2));
                app.q3Slider.Value = rad2deg(q1(1,3));
                app.q4Slider.Value = rad2deg(q1(1,4));
                app.q5Slider.Value = rad2deg(q1(1,5));
                app.q6Slider.Value = rad2deg(q1(1,6));
                
                % update textbox values in degrees
                app.q1Value.Value = rad2deg(q1(1,1));
                app.q2Value.Value = rad2deg(q1(1,2));
                app.q3Value.Value = rad2deg(q1(1,3));
                app.q4Value.Value = rad2deg(q1(1,4));
                app.q5Value.Value = rad2deg(q1(1,5));
                app.q6Value.Value = rad2deg(q1(1,6));
            end
            
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueY = app.YSpinner.Value;                                    % get spinner value
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));               % get old XYZ value from qDenso
                XYZ(1,2) = valueY;                                              % update Y
                q1 = app.modelDenso.model.ikcon(transl(XYZ));                     % get new q
                app.modelDenso.model.animate(q1);                                 % update model
                
                % store new qDenso
                app.qDenso = q1;
                
                % update slider values in degrees
                app.q1Slider.Value = rad2deg(q1(1,1));
                app.q2Slider.Value = rad2deg(q1(1,2));
                app.q3Slider.Value = rad2deg(q1(1,3));
                app.q4Slider.Value = rad2deg(q1(1,4));
                app.q5Slider.Value = rad2deg(q1(1,5));
                app.q6Slider.Value = rad2deg(q1(1,6));
    
                % update textbox values in degrees
                app.q1Value.Value = rad2deg(q1(1,1));
                app.q2Value.Value = rad2deg(q1(1,2));
                app.q3Value.Value = rad2deg(q1(1,3));
                app.q4Value.Value = rad2deg(q1(1,4));
                app.q5Value.Value = rad2deg(q1(1,5));
                app.q6Value.Value = rad2deg(q1(1,6));
            else
                valueY = app.YSpinner.Value;                                    % get spinner value
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));               % get old XYZ value from qDenso
                XYZ(1,2) = valueY;                                              % update Y
                q1 = app.modelLinearUR5.model.ikcon(transl(XYZ));                     % get new q
                app.modelLinearUR5.model.animate(q1);                                 % update model
                
                % store new qDenso
                app.qLinearUR5 = q1;
                
                % update slider values in degrees
                app.q1Slider.Value = rad2deg(q1(1,1));
                app.q2Slider.Value = rad2deg(q1(1,2));
                app.q3Slider.Value = rad2deg(q1(1,3));
                app.q4Slider.Value = rad2deg(q1(1,4));
                app.q5Slider.Value = rad2deg(q1(1,5));
                app.q6Slider.Value = rad2deg(q1(1,6));
    
                % update textbox values in degrees
                app.q1Value.Value = rad2deg(q1(1,1));
                app.q2Value.Value = rad2deg(q1(1,2));
                app.q3Value.Value = rad2deg(q1(1,3));
                app.q4Value.Value = rad2deg(q1(1,4));
                app.q5Value.Value = rad2deg(q1(1,5));
                app.q6Value.Value = rad2deg(q1(1,6));
            end
            
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueZ = app.ZSpinner.Value;                                    % get spinner value
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));               % get old XYZ value from qDenso
                XYZ(1,3) = valueZ;                                              % update Y
                q1 = app.modelDenso.model.ikcon(transl(XYZ));                     % get new q
                app.modelDenso.model.animate(q1);                                 % update model
                
                % store new qDenso
                app.qDenso = q1;      
                
                % update slider values in degrees
                app.q1Slider.Value = rad2deg(q1(1,1));
                app.q2Slider.Value = rad2deg(q1(1,2));
                app.q3Slider.Value = rad2deg(q1(1,3));
                app.q4Slider.Value = rad2deg(q1(1,4));
                app.q5Slider.Value = rad2deg(q1(1,5));
                app.q6Slider.Value = rad2deg(q1(1,6));
    
                % update textbox values in degrees
                app.q1Value.Value = rad2deg(q1(1,1));
                app.q2Value.Value = rad2deg(q1(1,2));
                app.q3Value.Value = rad2deg(q1(1,3));
                app.q4Value.Value = rad2deg(q1(1,4));
                app.q5Value.Value = rad2deg(q1(1,5));
                app.q6Value.Value = rad2deg(q1(1,6));
            else
                valueZ = app.YSpinner.Value;                                    % get spinner value
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));               % get old XYZ value from qDenso
                XYZ(1,3) = valueZ;                                              % update Y
                q1 = app.modelLinearUR5.model.ikcon(transl(XYZ));                     % get new q
                app.modelLinearUR5.model.animate(q1);                                 % update model
                
                % store new qDenso
                app.qLinearUR5 = q1;
                
                % update slider values in degrees
                app.q1Slider.Value = q1(1,1);
                app.q2Slider.Value = rad2deg(q1(1,2));
                app.q3Slider.Value = rad2deg(q1(1,3));
                app.q4Slider.Value = rad2deg(q1(1,4));
                app.q5Slider.Value = rad2deg(q1(1,5));
                app.q6Slider.Value = rad2deg(q1(1,6));
    
                % update textbox values in degrees
                app.q1Value.Value = rad2deg(q1(1,1));
                app.q2Value.Value = rad2deg(q1(1,2));
                app.q3Value.Value = rad2deg(q1(1,3));
                app.q4Value.Value = rad2deg(q1(1,4));
                app.q5Value.Value = rad2deg(q1(1,5));
                app.q6Value.Value = rad2deg(q1(1,6));
            end
            
        end

        % Value changed function: q1Value
        function q1ValueValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueQ1 = app.q1Value.Value;                                    % get slider value
                app.qDenso(1,1) = deg2rad(valueQ1);                               % update stored qDenso in rads
                app.modelDenso.model.animate(app.qDenso);                           % update model
                app.q1Slider.Value = rad2deg(app.qDenso(1,1));                    % update slider
    
                % get XYZ and update spinner values
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            else
                valueQ1 = app.q1Value.Value;                                    % get slider value
                app.qLinearUR5(1,1) = deg2rad(valueQ1);                               % update stored qDenso in rads
                app.modelLinearUR5.model.animate(app.qLinearUR5);                           % update model
                app.q1Slider.Value = rad2deg(app.qLinearUR5(1,1));                    % update slider
    
                % get XYZ and update spinner values
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            end
            
        end

        % Value changed function: q2Value
        function q2ValueValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueQ2 = app.q2Value.Value;                                    % get slider value
                app.qDenso(1,2) = deg2rad(valueQ2);                               % update stored qDenso in rads
                app.modelDenso.model.animate(app.qDenso);                           % update model
                app.q2Slider.Value = rad2deg(app.qDenso(1,2));                    % update slider
    
                % get XYZ and update spinner values
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            else
                valueQ2 = app.q2Value.Value;                                    % get slider value
                app.qLinearUR5(1,2) = deg2rad(valueQ2);                               % update stored qDenso in rads
                app.modelLinearUR5.model.animate(app.qLinearUR5);                           % update model
                app.q2Slider.Value = rad2deg(app.qLinearUR5(1,2));                    % update slider
    
                % get XYZ and update spinner values
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            end
            
        end

        % Value changed function: q3Value
        function q3ValueValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueQ3 = app.q3Value.Value;                                    % get slider value
                app.qDenso(1,3) = deg2rad(valueQ3);                               % update stored qDenso in rads
                app.modelDenso.model.animate(app.qDenso);                           % update model
                app.q3Slider.Value = rad2deg(app.qDenso(1,3));                    % update slider
    
                % get XYZ and update spinner values
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            else
                valueQ3 = app.q3Value.Value;                                    % get slider value
                app.qLinearUR5(1,3) = deg2rad(valueQ3);                               % update stored qDenso in rads
                app.modelLinearUR5.model.animate(app.qLinearUR5);                           % update model
                app.q3Slider.Value = rad2deg(app.qLinearUR5(1,3));                    % update slider
    
                % get XYZ and update spinner values
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            end
            
        end

        % Value changed function: q4Value
        function q4ValueValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueQ4 = app.q4Value.Value;                                    % get slider value
                app.qDenso(1,4) = deg2rad(valueQ4);                               % update stored qDenso in rads
                app.modelDenso.model.animate(app.qDenso);                           % update model
                app.q4Slider.Value = rad2deg(app.qDenso(1,4));                    % update slider
    
                % get XYZ and update spinner values
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            else
                valueQ4 = app.q4Value.Value;                                    % get slider value
                app.qLinearUR5(1,4) = deg2rad(valueQ4);                               % update stored qDenso in rads
                app.modelLinearUR5.model.animate(app.qLinearUR5);                           % update model
                app.q4Slider.Value = rad2deg(app.qLinearUR5(1,4));                    % update slider
    
                % get XYZ and update spinner values
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            end
            
        end

        % Value changed function: q5Value
        function q5ValueValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueQ5 = app.q5Value.Value;                                    % get slider value
                app.qDenso(1,5) = deg2rad(valueQ5);                               % update stored qDenso in rads
                app.modelDenso.model.animate(app.qDenso);                           % update model
                app.q5Slider.Value = rad2deg(app.qDenso(1,5));                    % update slider
    
                % get XYZ and update spinner values
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            else
                valueQ5 = app.q5Value.Value;                                    % get slider value
                app.qLinearUR5(1,5) = deg2rad(valueQ5);                               % update stored qDenso in rads
                app.modelLinearUR5.model.animate(app.qLinearUR5);                           % update model
                app.q5Slider.Value = rad2deg(app.qLinearUR5(1,5));                    % update slider
    
                % get XYZ and update spinner values
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            end
            
        end

        % Value changed function: q6Value
        function q6ValueValueChanged(app, event)
            if app.RobotDropDown.Value == "DensoVS068"
                valueQ6 = app.q6Value.Value;                                    % get slider value
                app.qDenso(1,6) = deg2rad(valueQ6);                               % update stored qDenso in rads
                app.modelDenso.model.animate(app.qDenso);                           % update model
                app.q6Slider.Value = rad2deg(app.qDenso(1,6));                    % update slider
    
                % get XYZ and update spinner values
                XYZ = transl(app.modelDenso.model.fkine(app.qDenso));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            else
                valueQ6 = app.q6Value.Value;                                    % get slider value
                app.qLinearUR5(1,6) = deg2rad(valueQ6);                               % update stored qDenso in rads
                app.modelLinearUR5.model.animate(app.qLinearUR5);                           % update model
                app.q6Slider.Value = rad2deg(app.qLinearUR5(1,6));                    % update slider
    
                % get XYZ and update spinner values
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            end
            
        end

        % Value changed function: RobotDropDown
        function RobotDropDownValueChanged(app, event)
            % update slider limits for each robot
            if app.RobotDropDown.Value == "DensoVS068"
                app.q1Slider.Limits = [-170 170];
                app.q2Slider.Limits = [-100 135];
                app.q3Slider.Limits = [-120 153];
                app.q4Slider.Limits = [-270 270];
                app.q5Slider.Limits = [-120 120];
                app.q6Slider.Limits = [-360 360];
            else
                app.q1Slider.Limits = [-0.8 -0.01];
                app.q2Slider.Limits = [-360 360];
                app.q3Slider.Limits = [-90 90];
                app.q4Slider.Limits = [-170 170];
                app.q5Slider.Limits = [-360 360];
                app.q6Slider.Limits = [-360 360];
                app.q7Slider.Limits = [-360 360];
            end
            
        end

        % Value changed function: q7Slider
        function q7SliderValueChanged(app, event)
           if app.RobotDropDown.Value == "DensoVS068"
                
            else
                valueQ7 = app.q7Slider.Value;                                   % get slider value
                app.qLinearUR5(1,7) = deg2rad(valueQ7);                               % update stored qDenso in rads
                app.modelLinearUR5.model.animate(app.qLinearUR5);                           % update model
                app.q7Value.Value = valueQ7;                                    % update textbox
                
                % get XYZ and update spinner values
                XYZ = transl(app.modelLinearUR5.model.fkine(app.qLinearUR5));
                app.XSpinner.Value = XYZ(1,1);
                app.YSpinner.Value = XYZ(1,2);
                app.ZSpinner.Value = XYZ(1,3);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'TestRobotPlot_V2';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            title(app.plot, 'Title')
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            zlabel(app.plot, 'Z')
            app.RobotDropDownLabel = uilabel(app.figure1);
            app.RobotDropDownLabel.Text = 'RobotDropDown';
            app.RobotDropDown = uidropdown(app.figure1);
            app.RobotDropDown.Items = {'LinearUR5', 'DensoVS068'};
            app.RobotDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotDropDownValueChanged, true);
            app.RobotDropDown.Value = 'DensoVS068';
            app.q5Value = uieditfield(app.figure1, 'numeric');
            app.q5Value.ValueChangedFcn = createCallbackFcn(app, @q5ValueValueChanged, true);
            app.q6Value = uieditfield(app.figure1, 'numeric');
            app.q6Value.ValueChangedFcn = createCallbackFcn(app, @q6ValueValueChanged, true);
            app.q4Value = uieditfield(app.figure1, 'numeric');
            app.q4Value.ValueChangedFcn = createCallbackFcn(app, @q4ValueValueChanged, true);
            app.q3Value = uieditfield(app.figure1, 'numeric');
            app.q3Value.ValueChangedFcn = createCallbackFcn(app, @q3ValueValueChanged, true);
            app.q2Value = uieditfield(app.figure1, 'numeric');
            app.q2Value.ValueChangedFcn = createCallbackFcn(app, @q2ValueValueChanged, true);
            app.q1Value = uieditfield(app.figure1, 'numeric');
            app.q1Value.ValueChangedFcn = createCallbackFcn(app, @q1ValueValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.figure1);
            app.ZSpinnerLabel.Text = 'ZSpinner';
            app.ZSpinner = uispinner(app.figure1);
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.YSpinnerLabel = uilabel(app.figure1);
            app.YSpinnerLabel.Text = 'YSpinner';
            app.YSpinner = uispinner(app.figure1);
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.XSpinnerLabel = uilabel(app.figure1);
            app.XSpinnerLabel.Text = 'XSpinner';
            app.XSpinner = uispinner(app.figure1);
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.q1SliderLabel = uilabel(app.figure1);
            app.q1SliderLabel.Text = 'q1Slider';
            app.q1Slider = uislider(app.figure1);
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q2SliderLabel = uilabel(app.figure1);
            app.q2SliderLabel.Text = 'q2Slider';
            app.q2Slider = uislider(app.figure1);
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q3SliderLabel = uilabel(app.figure1);
            app.q3SliderLabel.Text = 'q3Slider';
            app.q3Slider = uislider(app.figure1);
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q4SliderLabel = uilabel(app.figure1);
            app.q4SliderLabel.Text = 'q4Slider';
            app.q4Slider = uislider(app.figure1);
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q5SliderLabel = uilabel(app.figure1);
            app.q5SliderLabel.Text = 'q5Slider';
            app.q5Slider = uislider(app.figure1);
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q6SliderLabel = uilabel(app.figure1);
            app.q6SliderLabel.Text = 'q6Slider';
            app.q6Slider = uislider(app.figure1);
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.q7SliderLabel = uilabel(app.figure1);
            app.q7SliderLabel.Text = 'q7Slider';
            app.q7Slider = uislider(app.figure1);
            app.q7Slider.ValueChangedFcn = createCallbackFcn(app, @q7SliderValueChanged, true);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = teachGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end